classdef DAST_V1_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        TestTab                        matlab.ui.container.Tab
        W8Button                       matlab.ui.control.StateButton
        W7Button                       matlab.ui.control.StateButton
        W6Button                       matlab.ui.control.StateButton
        W5Button                       matlab.ui.control.StateButton
        W4Button                       matlab.ui.control.StateButton
        W3Button                       matlab.ui.control.StateButton
        W2Button                       matlab.ui.control.StateButton
        W1Button                       matlab.ui.control.StateButton
        PausecontinueButton            matlab.ui.control.StateButton
        MarkallButton                  matlab.ui.control.StateButton
        ExeptionsCheckBox              matlab.ui.control.CheckBox
        Instructions                   matlab.ui.control.Label
        Sentences                      matlab.ui.control.Label
        currentSent                    matlab.ui.control.Label
        currentList                    matlab.ui.control.Label
        SentenceNoLabel                matlab.ui.control.Label
        AcceptandcontinueButton        matlab.ui.control.Button
        DanskStningstestLabel          matlab.ui.control.Label
        StopButton                     matlab.ui.control.Button
        StarttestButton                matlab.ui.control.Button
        AdditionalsettingsTab          matlab.ui.container.Tab
        Panel_2                        matlab.ui.container.Panel
        MaskertimeEditField            matlab.ui.control.EditField
        MaskertimeEditFieldLabel       matlab.ui.control.Label
        secLabel                       matlab.ui.control.Label
        ScoringtypeButtonGroup         matlab.ui.container.ButtonGroup
        WordbywordButton               matlab.ui.control.RadioButton
        KeywordbasedButton             matlab.ui.control.RadioButton
        PresentationButtonGroup        matlab.ui.container.ButtonGroup
        VideosetupButton               matlab.ui.control.Button
        AudiovisualButton              matlab.ui.control.RadioButton
        AudioonlyButton                matlab.ui.control.RadioButton
        OutputsetupButtonGroup         matlab.ui.container.ButtonGroup
        CalibrationButton              matlab.ui.control.Button
        LoudspeakersButton             matlab.ui.control.RadioButton
        HeadphonesButton               matlab.ui.control.RadioButton
        TestmethodButtonGroup          matlab.ui.container.ButtonGroup
        AdaptiveButton                 matlab.ui.control.RadioButton
        FixedSNRButton                 matlab.ui.control.RadioButton
        Panel                          matlab.ui.container.Panel
        AudiofolderpathEditField       matlab.ui.control.EditField
        AudiofolderpathEditFieldLabel  matlab.ui.control.Label
        ChooseButton                   matlab.ui.control.Button
        TabGroup2                      matlab.ui.container.TabGroup
        SettingsTab                    matlab.ui.container.Tab
        MaskerlevelEditField           matlab.ui.control.EditField
        MaskerlevelEditFieldLabel      matlab.ui.control.Label
        TargetlevelEditField           matlab.ui.control.EditField
        TargetlevelEditFieldLabel      matlab.ui.control.Label
        MaskertypeDropDown             matlab.ui.control.DropDown
        MaskertypeDropDownLabel        matlab.ui.control.Label
        ListnumberDropDown             matlab.ui.control.DropDown
        ListnumberDropDownLabel        matlab.ui.control.Label
        TalkerDropDown                 matlab.ui.control.DropDown
        TalkerDropDownLabel            matlab.ui.control.Label
        dBSPLLabel_2                   matlab.ui.control.Label
        dBSPLLabel                     matlab.ui.control.Label
        UploadCSVTab                   matlab.ui.container.Tab
        PleaseuploadacsvfilewiththecorrectformatLabel  matlab.ui.control.Label
        CSVfileEditField               matlab.ui.control.EditField
        CSVfileEditFieldLabel          matlab.ui.control.Label
        UploadButton                   matlab.ui.control.Button
        CustomizeTab                   matlab.ui.container.Tab
        TalkerDropDown_2               matlab.ui.control.DropDown
        TalkerDropDown_2Label          matlab.ui.control.Label
        MaskerlevelEditField_2         matlab.ui.control.EditField
        MaskerlevelEditField_2Label    matlab.ui.control.Label
        TargetlevelEditField_2         matlab.ui.control.EditField
        TargetlevelEditField_2Label    matlab.ui.control.Label
        dBSPLLabel_4                   matlab.ui.control.Label
        dBSPLLabel_3                   matlab.ui.control.Label
        RandomizewithinlistCheckBox    matlab.ui.control.CheckBox
        BasictestinformationPanel      matlab.ui.container.Panel
        FilenameEditField              matlab.ui.control.EditField
        FilenameEditFieldLabel         matlab.ui.control.Label
        SubjectNoEditField             matlab.ui.control.EditField
        SubjectNoEditFieldLabel        matlab.ui.control.Label
        TesterintialsEditField         matlab.ui.control.EditField
        TesterintialsEditFieldLabel    matlab.ui.control.Label
    end


    properties (Access = public)

        % % Definition of global variables
        % % to use across different functions

        Date
        currentAudioDir = fullfile(pwd, 'audio');

        % File locations
        targetDir; maskerDir; targetFile; maskerFile;
        resultName; resultDir; resultFile; target; masker;

        % Settings
        maskerOnsetTime; maskerOffsetTime;
        testMethod;scoringType;
        listNum; talker; maskerType; targetLevel; maskerLevel;

        % current sentences
        sentences; sentencesDir; sent; sentTot; sentText; iteration;

        % results
        totalWordsCorrect;totalWordsTest; Percentage;
        soundObj

        % buttons
        totButtons = 8;
        KeywordsButtons = [];


    end

    methods (Access = public)

        function playSound(app)

            % Read the signals:
            [targetSignal,Fs] = audioread(app.target);
            [maskerSignal,Fs_masker] = audioread(app.masker);
            % (Combine target + masker)

            % Play sound object
            % (for now, no calibration or mix with masker)
            app.soundObj = audioplayer(targetSignal, Fs);
            %             playblocking(app.soundObj);

        end
    end

    methods (Access = public)

        function updateInformation(app)

            app.iteration = app.iteration + 1;

            % ----------------------------------------------------

            % Get current sentence number and text
            app.sent = app.sentences.Number(app.iteration); % current sentence
            app.sentText = app.sentences.Sentence(app.sent); % current text sentence

            % Update fields
            app.currentSent.Text = sprintf('%i / %i', app.sent, app.sentTot);
            app.currentList.Text = app.ListnumberDropDown.Value;

            % ----------------------------------------------------

            % Buttons: keywords or word-based
            buttonSz = 15; % initial button size (wo. words)
            letterSz = 10; % increment for each letter in word
            wordSpacing = 5; % spacing between buttons (words)

            % make all buttons invisible
            for button = 1:app.totButtons
                app.(sprintf('W%iButton', button)).Visible = "off";
            end

            str = string(app.sentences.Sentence(app.iteration));
            Words = strsplit(str);
            nWords = numel(Words);
            nLetters = strlength(str);
            Keywords = string(app.sentences.Keywords(app.iteration));

            % initializing initial button position
            centerGrid = app.Sentences.Position(1)+ app.Sentences.Position(3)/2;
            totalSentWidth = buttonSz + wordSpacing + nLetters*(letterSz);
            initPosition = centerGrid - totalSentWidth/2;
            totalSize = initPosition;

            for i = 1:nWords

                % --- displaying each word onto buttons
                app.(sprintf('W%iButton', i)).Visible = "on";
                app.(sprintf('W%iButton', i)).Text = Words(i);

                % Keywords: find in which button and bold text + saving
                if contains(Words(i),strsplit(Keywords,', '),'IgnoreCase',true)
                    app.(sprintf('W%iButton', i)).FontWeight = 'bold';
                    app.KeywordsButtons = [app.KeywordsButtons i];
                end

                % changing width (Pos 3) ---
                app.(sprintf('W%iButton', i)).Position(3) = buttonSz+(letterSz*strlength(Words(i)));

                % --- spacing and locating each button (Pos 1) ---
                app.(sprintf('W%iButton', i)).Position(1) = totalSize;
                % current size + width of last button + space between words
                totalSize = totalSize + app.(sprintf('W%iButton', i)).Position(3) + wordSpacing;

            end

            % Disable non-keyword buttons (when keyword-based scoring)
            if app.scoringType == "Keyword-based"
                for m = 1:app.totButtons
                    if ~any(app.KeywordsButtons == m)
                        app.(sprintf('W%iButton', m)).Enable = "off";
                    end
                end
            end

            % To save keywords
            Keywords = split(app.sentences.Keywords(app.sent), ',');

            % ----------------------------------------------------

            % Taget and masker
            app.targetFile = sprintf('%04d.wav', app.sent); %targetInit = 'AG';
            app.maskerFile = strcat(app.maskerType, '.wav');
            app.target = fullfile(app.targetDir, app.targetFile);
            app.masker = fullfile(app.maskerDir, app.maskerFile);

        end
    end

    methods (Access = private)

        function disableButtons_initialState(app)

            % User input enabled
            app.StarttestButton.Enable = "on";
            app.BasictestinformationPanel.Enable = "on";
            app.Panel.Enable = "on";
            app.Panel_2.Enable = "on";

            % Test tab disabled
            app.PausecontinueButton.Enable = "off";
            app.StopButton.Enable = "off";
            app.MarkallButton.Enable = "off";
            app.ExeptionsCheckBox.Enable = "off";

            app.KeywordsButtons =[];
            app.MarkallButton.Value = 0;
            app.ExeptionsCheckBox.Value = 0;
            app.PausecontinueButton.Value = 0;

            for i = 1:app.totButtons
                app.(sprintf('W%iButton', i)).Visible = "off";
                app.(sprintf('W%iButton', i)).Value = 0;
                app.(sprintf('W%iButton', i)).FontWeight = 'normal';
            end

            app.Sentences.Visible = "off";
            app.Instructions.Visible = "off";
            app.SentenceNoLabel.Visible = "off";
            app.currentList.Visible = "off";
            app.currentSent.Visible = "off";

            app.AcceptandcontinueButton.Enable = "off";

        end

        function enableButtons_testState(app)

            % User input disabled
            app.StarttestButton.Enable = "off";
            app.BasictestinformationPanel.Enable = "off";
            app.Panel.Enable = "off";
            app.Panel_2.Enable = "off";
            %             app.TabGroup.SelectedTab = app.TestTab;

            % Test tab enabled
            app.PausecontinueButton.Enable = "on";
            app.StopButton.Enable = "on";
            app.MarkallButton.Enable = "on";
            app.ExeptionsCheckBox.Enable = "on";

            for i = 1:app.totButtons
                app.(sprintf('W%iButton', i)).Visible = "on";
                app.(sprintf('W%iButton', i)).Enable = "on";

            end
            app.Sentences.Visible = "on";
            app.Instructions.Visible = "on";
            app.SentenceNoLabel.Visible = "on";
            app.currentList.Visible = "on";
            app.currentSent.Visible = "on";
        end

        function getSettings(app)

            % ------------------------------------------------------------
            % Get config from 'Settings' and 'More settings' panel

            % Settings
            app.listNum = app.ListnumberDropDown.Value;
            app.talker = app.TalkerDropDown.Value;
            app.maskerType = app.MaskertypeDropDown.Value;
            app.targetLevel = double(string(app.TargetlevelEditField.Value));
            app.maskerLevel = double(string(app.MaskerlevelEditField.Value));

            % More settings
            app.maskerOnsetTime = double(string(app.MaskertimeEditField.Value)); % [sec.] time between noise onset and sentence start
            app.maskerOffsetTime = double(string(app.MaskertimeEditField.Value)); % [sec.] time between noise offset and end

            if app.FixedSNRButton.Value == 1; app.testMethod = 'Fixed SNR';
            else; app.testMethod = 'Adaptive SNR'; end
            if app.KeywordbasedButton.Value == 1; app.scoringType = 'Keyword-based';
            else; app.scoringType = 'Word-by-word'; end

            % Test method, rating, output setup, presentation...
            % ...
            % ...
            % ...

            % ------------------------------------------------------------

            % File locations:
            app.targetDir = fullfile(app.currentAudioDir,'Speech',...
                app.talker, app.listNum); % targets dir
            app.maskerDir = fullfile(app.currentAudioDir,'Noise'); % masker dir

            app.sentencesDir = fullfile(pwd, 'files', 'DAST-sentences.xlsx'); % file with DAST sentences
            % CHANGE HERE IF INPUT CSV

            app.resultDir = fullfile(pwd, 'results'); % results dir
            app.resultName = app.FilenameEditField.Value; % results file name
            app.resultFile = fullfile(app.resultDir, [app.resultName, '.csv']);

            % if csv file already exists (duplicating file name)
            newName = app.resultName;
            for i = 1:30
                if isfile(app.resultFile)
                    newName = strcat(app.resultName, '.', num2str(i));
                    app.resultFile = fullfile(app.resultDir, [newName, '.csv']);
                else
                    break
                end
            end
            if strcmp(app.resultName,newName) == 0
                app.resultName = newName;
            end

            audioFilesInFolder = dir(fullfile(app.targetDir,'*.wav'));
            app.sentTot = numel(audioFilesInFolder); % total num of sentences per list

            app.sentences = readtable(app.sentencesDir); % file with DAST sentences
            % ------------------------------------------------------------

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Intializing variables
            formatOut = 'yyyy_mm_dd';
            app.Date = datestr(now,formatOut);
            app.FilenameEditField.Value = sprintf(app.Date);
            app.AudiofolderpathEditField.Value = app.currentAudioDir;


            % disable buttons
            disableButtons_initialState(app)


        end

        % Value changed function: FilenameEditField, SubjectNoEditField, 
        % ...and 1 other component
        function TestInfoChanged(app, event)
            if isempty(app.TesterintialsEditField.Value); Leader = '';
            else; Leader = strcat('_', app.TesterintialsEditField.Value); end
            if isempty(app.SubjectNoEditField.Value); Subject = '';
            else; Subject = strcat('TP', app.SubjectNoEditField.Value, '_'); end

            app.FilenameEditField.Value = sprintf(strcat(Subject, app.Date, Leader));
        end

        % Button pushed function: StarttestButton
        function StarttestButtonPushed(app, event)

            % Enable buttons
            enableButtons_testState(app)
            app.Sentences.Text = 'Sentence';

            % Get information from user
            getSettings(app)

            %--------------------------------------------------------
            % write basic information in csv
            file = fopen(app.resultFile, 'w');
            fprintf(file, "Result file DAST test\n");
            fprintf(file, "Filename: %s\n", app.resultName);
            fprintf(file, "Date and time: %s\n", datestr(now,'dd-mmm-yyyy HH:MM:SS'));
            fprintf(file, "Procedure: %s\n", app.testMethod);
            fprintf(file, "Scoring: %s\n", app.scoringType);
            fprintf(file, "Start level of target: %i dB SPL\n", app.targetLevel);
            fprintf(file, "Start level of masker: %i dB SPL\n", app.maskerLevel);
            fprintf(file, "Speaker: %s, Masker: %s\n", app.talker, app.maskerType);
            fprintf(file, "\n");
            fprintf(file, "List,Sentence,SNR,Correct,Total,Exceptions,Correct\n");
            if app.scoringType == "Keyword-based"
                fprintf(file, "number,index,[dB],words,words,made,keywords\n");
            else
                fprintf(file, "number,index,[dB],words,words,made,words\n");
            end
            fclose(file);
            %--------------------------------------------------------

            % Update files names
            app.totalWordsCorrect = 0;
            app.totalWordsTest = 0;
            app.Percentage = 0;
            app.iteration = 0;
            updateInformation(app)

            % Play sound
            playSound(app)

            %--------------------------------------------------------

            % Accept and continue button: save results, play next sentence
            app.AcceptandcontinueButton.Enable = "on";


        end

        % Value changed function: MarkallButton
        function MarkallButtonValueChanged(app, event)
            value = app.MarkallButton.Value;

            for i = 1:app.totButtons
                if (value == 1 && app.scoringType ~= "Keyword-based") ||...
                        (value == 1 && app.scoringType == "Keyword-based" && any(app.KeywordsButtons == i))
                    app.(sprintf('W%iButton', i)).Value = 1;
                else
                    app.(sprintf('W%iButton', i)).Value = 0;

                end
            end

        end

        % Callback function
        function KeywordSelected(app, event)

        end

        % Button pushed function: AcceptandcontinueButton
        function AcceptandcontinueButtonPushed(app, event)

            app.AcceptandcontinueButton.Enable = "off";
            %--------------------------------------------------------

            % calculate and save results in csv
            file = fopen(app.resultFile, 'a+');

            Words = [];

            if app.scoringType == "Keyword-based"
                % Saving the correctly repeated keywords in variable "Words"
                for j = 1:length(app.KeywordsButtons)
                    if app.(sprintf('W%iButton', app.KeywordsButtons(j))).Value == 1
                        Words = [Words string(app.(sprintf('W%iButton', app.KeywordsButtons(j))).Text)];
                    end
                end
                numWordsSent = 3;

            else
                % Saving the correctly repeated words in variable "Words"
                for i = 1:app.totButtons
                    if app.(sprintf('W%iButton', i)).Value == 1
                        Words = [Words string(app.(sprintf('W%iButton', i)).Text)];
                    end
                end
                numWordsSent = numel(strsplit(string(app.sentText)));

            end

            numCorrectWordsSent = length(Words);
            SNR = app.targetLevel - app.maskerLevel;
            exceptions = app.ExeptionsCheckBox.Value;

            app.totalWordsCorrect = app.totalWordsCorrect + numCorrectWordsSent;
            app.totalWordsTest = app.totalWordsTest + numWordsSent;
            app.Percentage = (app.totalWordsCorrect/app.totalWordsTest)*100;

            % Writing results
            if numCorrectWordsSent > 0
                totalStrings = join(repmat("%s",1,numCorrectWordsSent),',');
            else; totalStrings = "%s";
            end

            fprintf(file, strcat("%s,%i,%i,%i,%i,%i,", totalStrings, "\n"),...
                erase(app.listNum, "List "), app.iteration, SNR, numCorrectWordsSent,...
                numWordsSent, exceptions, Words);

            fclose(file);
            %--------------------------------------------------------

            for i = 1:app.totButtons
                app.(sprintf('W%iButton', i)).Value = 0;
                app.(sprintf('W%iButton', i)).FontWeight = 'normal';
                app.(sprintf('W%iButton', i)).Enable = 'on';

            end
            app.KeywordsButtons =[];
            app.MarkallButton.Value = 0;
            app.ExeptionsCheckBox.Value = 0;

            if app.iteration < app.sentTot

                %--------------------------------------------------------
                % Update file locations/names
                updateInformation(app)

                % Play sound
                playSound(app)
                %--------------------------------------------------------

                % Accept and continue button: save results, play next sentence
                app.AcceptandcontinueButton.Enable = "on";

            else
                disableButtons_initialState(app);
                app.Sentences.Visible = "on";
                app.Sentences.Text = strcat(app.listNum, ' scoring completed');
                app.Sentences.FontSize = 20;

                % Write final results
                file = fopen(app.resultFile, 'a+');
                fprintf(file, "\n");
                fprintf(file, "Correct,Words,Percentage\n");
                fprintf(file, "%i,%i,%0.1f%%\n",...
                    app.totalWordsCorrect, app.totalWordsTest, app.Percentage);
                fclose(file);
            end


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)

            % disable buttons
            disableButtons_initialState(app)

            app.Sentences.Visible = "on";
            app.Sentences.Text = 'Test terminated';
            app.Sentences.FontSize = 20;

            % Write partial results
            file = fopen(app.resultFile, 'a+');
            fprintf(file, "Measurement cancelled\n");
            fprintf(file, "\n");
            fprintf(file, "Correct,Words,Percentage\n");
            fprintf(file, "%i,%i,%0.1f%%\n",...
                app.totalWordsCorrect, app.totalWordsTest, app.Percentage);
            fclose(file);

        end

        % Callback function
        function PausecontinueButtonPushed(app, event)


        end

        % Value changed function: PausecontinueButton
        function PausecontinueButtonValueChanged(app, event)

            % Pressing pause
            if app.PausecontinueButton.Value == 1

                app.Sentences.Text = 'Test paused';
                app.Sentences.FontSize = 20;

                % make all buttons invisible
                for button = 1:app.totButtons
                    app.(sprintf('W%iButton', button)).Visible = "off";
                end
                app.AcceptandcontinueButton.Enable = "off";
                app.MarkallButton.Visible = "off";
                app.ExeptionsCheckBox.Visible = "off";
                app.Instructions.Visible = "off";

            else

                % Pressing again for continuing test enabling buttons
                app.Sentences.Text = 'Sentence';
                app.Sentences.FontSize = 14;

                % make all buttons invisible
                for button = 1:app.totButtons
                    app.(sprintf('W%iButton', button)).Visible = "on";
                end
                app.AcceptandcontinueButton.Enable = "on";
                app.MarkallButton.Visible = "on";
                app.ExeptionsCheckBox.Visible = "on";
                app.Instructions.Visible = "on";


                % Playing sentence again
                playSound(app)

                app.AcceptandcontinueButton.Enable = "on";

            end

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)

            %             % While test is in progress, 'more settings' tab is disabled
            %             % (can't click on it)
            %             if app.StarttestButton.Enable == "off"
            %                 app.TabGroup.SelectedTab = app.TestTab;
            %             end
        end

        % Value changed function: W1Button, W2Button, W3Button, W4Button, 
        % ...and 4 other components
        function wordSelected(app, event)
            values = zeros(1,app.totButtons);
            for i = 1:app.totButtons
                values(i) =  app.(sprintf('W%iButton', i)).Value;
            end

            if any(values == 0)
                app.MarkallButton.Value = 0;
            end

        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [filename,pathname] = uigetfile('*.csv');
            fullfilename = fullfile(pathname,filename);
            app.CSVfileEditField.Value = filename;
        end

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)
            audiopath = uigetdir;
            app.AudiofolderpathEditField.Value = audiopath;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.BasictestinformationPanel = uipanel(app.GridLayout);
            app.BasictestinformationPanel.Title = 'Basic test information';
            app.TesterintialsEditFieldLabel = uilabel(app.BasictestinformationPanel);
            app.TesterintialsEditFieldLabel.Text = 'Tester intials';
            app.TesterintialsEditField = uieditfield(app.BasictestinformationPanel, 'text');
            app.TesterintialsEditField.ValueChangedFcn = createCallbackFcn(app, @TestInfoChanged, true);
            app.SubjectNoEditFieldLabel = uilabel(app.BasictestinformationPanel);
            app.SubjectNoEditFieldLabel.Text = 'Subject No.';
            app.SubjectNoEditField = uieditfield(app.BasictestinformationPanel, 'text');
            app.SubjectNoEditField.ValueChangedFcn = createCallbackFcn(app, @TestInfoChanged, true);
            app.FilenameEditFieldLabel = uilabel(app.BasictestinformationPanel);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.BasictestinformationPanel, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @TestInfoChanged, true);
            app.FilenameEditField.Editable = 'off';
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Tag = 'SettingsPanel';
            app.TabGroup2 = uitabgroup(app.Panel);
            app.SettingsTab = uitab(app.TabGroup2);
            app.SettingsTab.Title = 'Settings';
            app.dBSPLLabel = uilabel(app.SettingsTab);
            app.dBSPLLabel.Text = 'dB SPL';
            app.dBSPLLabel_2 = uilabel(app.SettingsTab);
            app.dBSPLLabel_2.Text = 'dB SPL';
            app.TalkerDropDownLabel = uilabel(app.SettingsTab);
            app.TalkerDropDownLabel.Text = 'Talker';
            app.TalkerDropDown = uidropdown(app.SettingsTab);
            app.TalkerDropDown.Items = {'Female 1', 'Female 2', 'Male 1', 'Male 2', 'Mixed/random'};
            app.TalkerDropDown.Value = 'Female 1';
            app.ListnumberDropDownLabel = uilabel(app.SettingsTab);
            app.ListnumberDropDownLabel.Text = 'List number';
            app.ListnumberDropDown = uidropdown(app.SettingsTab);
            app.ListnumberDropDown.Items = {'List 1', 'List 2', 'List 3', 'List 4', 'List 5', 'List 6', 'List 7', 'List 8', 'List 9', 'List 10', 'List 11', 'List 12', 'List 13', 'List 14', 'List 15', 'List 16', 'List 17', 'List 18', 'List 19', 'List 20', 'List 21', 'List 22', 'List 23', 'List 24', 'List 25', 'List 26'};
            app.ListnumberDropDown.Value = 'List 1';
            app.MaskertypeDropDownLabel = uilabel(app.SettingsTab);
            app.MaskertypeDropDownLabel.Text = 'Masker type';
            app.MaskertypeDropDown = uidropdown(app.SettingsTab);
            app.MaskertypeDropDown.Items = {'Unmodulated', 'Modulated', 'Sinusoidal', '4T-Babble'};
            app.MaskertypeDropDown.Value = 'Unmodulated';
            app.TargetlevelEditFieldLabel = uilabel(app.SettingsTab);
            app.TargetlevelEditFieldLabel.Text = 'Target level';
            app.TargetlevelEditField = uieditfield(app.SettingsTab, 'text');
            app.TargetlevelEditField.Value = '70';
            app.MaskerlevelEditFieldLabel = uilabel(app.SettingsTab);
            app.MaskerlevelEditFieldLabel.Text = 'Masker level';
            app.MaskerlevelEditField = uieditfield(app.SettingsTab, 'text');
            app.MaskerlevelEditField.Value = '70';
            app.UploadCSVTab = uitab(app.TabGroup2);
            app.UploadCSVTab.Title = 'Upload CSV';
            app.UploadButton = uibutton(app.UploadCSVTab, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.CSVfileEditFieldLabel = uilabel(app.UploadCSVTab);
            app.CSVfileEditFieldLabel.Text = 'CSV file';
            app.CSVfileEditField = uieditfield(app.UploadCSVTab, 'text');
            app.CSVfileEditField.Value = 'none.csv';
            app.PleaseuploadacsvfilewiththecorrectformatLabel = uilabel(app.UploadCSVTab);
            app.PleaseuploadacsvfilewiththecorrectformatLabel.Text = {'Upload a csv file with the settings for the personalized test in the correct format.'; 'Obs. one CSV file per Subject No.'};
            app.CustomizeTab = uitab(app.TabGroup2);
            app.CustomizeTab.Title = 'Customize';
            app.RandomizewithinlistCheckBox = uicheckbox(app.CustomizeTab);
            app.RandomizewithinlistCheckBox.Text = 'Randomize within list';
            app.dBSPLLabel_3 = uilabel(app.CustomizeTab);
            app.dBSPLLabel_3.Text = 'dB SPL';
            app.dBSPLLabel_4 = uilabel(app.CustomizeTab);
            app.dBSPLLabel_4.Text = 'dB SPL';
            app.TargetlevelEditField_2Label = uilabel(app.CustomizeTab);
            app.TargetlevelEditField_2Label.Text = 'Target level';
            app.TargetlevelEditField_2 = uieditfield(app.CustomizeTab, 'text');
            app.TargetlevelEditField_2.Value = '70';
            app.MaskerlevelEditField_2Label = uilabel(app.CustomizeTab);
            app.MaskerlevelEditField_2Label.Text = 'Masker level';
            app.MaskerlevelEditField_2 = uieditfield(app.CustomizeTab, 'text');
            app.MaskerlevelEditField_2.Value = '70';
            app.TalkerDropDown_2Label = uilabel(app.CustomizeTab);
            app.TalkerDropDown_2Label.Text = 'Talker';
            app.TalkerDropDown_2 = uidropdown(app.CustomizeTab);
            app.TalkerDropDown_2.Items = {'Female 1', 'Female 2', 'Male 1', 'Male 2', 'Mixed/random'};
            app.TalkerDropDown_2.Value = 'Female 1';
            app.ChooseButton = uibutton(app.Panel, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Text = 'Choose';
            app.AudiofolderpathEditFieldLabel = uilabel(app.Panel);
            app.AudiofolderpathEditFieldLabel.Text = 'Audio folder path';
            app.AudiofolderpathEditField = uieditfield(app.Panel, 'text');
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.TestTab = uitab(app.TabGroup);
            app.TestTab.Title = 'Test';
            app.StarttestButton = uibutton(app.TestTab, 'push');
            app.StarttestButton.ButtonPushedFcn = createCallbackFcn(app, @StarttestButtonPushed, true);
            app.StarttestButton.Text = 'Start test';
            app.StopButton = uibutton(app.TestTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.DanskStningstestLabel = uilabel(app.TestTab);
            app.DanskStningstestLabel.Text = 'Dansk Sætningstest';
            app.AcceptandcontinueButton = uibutton(app.TestTab, 'push');
            app.AcceptandcontinueButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptandcontinueButtonPushed, true);
            app.AcceptandcontinueButton.Text = 'Accept and continue';
            app.SentenceNoLabel = uilabel(app.TestTab);
            app.SentenceNoLabel.Text = 'Sentence No.';
            app.currentList = uilabel(app.TestTab);
            app.currentList.Tag = 'currentList';
            app.currentList.Text = 'currentList';
            app.currentSent = uilabel(app.TestTab);
            app.currentSent.Tag = 'currentSent';
            app.currentSent.Text = 'currentSent';
            app.Sentences = uilabel(app.TestTab);
            app.Sentences.Text = 'Sentence';
            app.Instructions = uilabel(app.TestTab);
            app.Instructions.Text = 'Mark correctly repeated words';
            app.ExeptionsCheckBox = uicheckbox(app.TestTab);
            app.ExeptionsCheckBox.Text = 'Exeptions';
            app.MarkallButton = uibutton(app.TestTab, 'state');
            app.MarkallButton.ValueChangedFcn = createCallbackFcn(app, @MarkallButtonValueChanged, true);
            app.MarkallButton.Text = 'Mark all';
            app.PausecontinueButton = uibutton(app.TestTab, 'state');
            app.PausecontinueButton.ValueChangedFcn = createCallbackFcn(app, @PausecontinueButtonValueChanged, true);
            app.PausecontinueButton.Text = 'Pause/continue';
            app.W1Button = uibutton(app.TestTab, 'state');
            app.W1Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W1Button.Text = 'W1';
            app.W2Button = uibutton(app.TestTab, 'state');
            app.W2Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W2Button.Text = 'W2';
            app.W3Button = uibutton(app.TestTab, 'state');
            app.W3Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W3Button.Text = 'W3';
            app.W4Button = uibutton(app.TestTab, 'state');
            app.W4Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W4Button.Text = 'W4';
            app.W5Button = uibutton(app.TestTab, 'state');
            app.W5Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W5Button.Text = 'W5';
            app.W6Button = uibutton(app.TestTab, 'state');
            app.W6Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W6Button.Text = 'W6';
            app.W7Button = uibutton(app.TestTab, 'state');
            app.W7Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W7Button.Text = 'W7';
            app.W8Button = uibutton(app.TestTab, 'state');
            app.W8Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W8Button.Text = 'W8';
            app.AdditionalsettingsTab = uitab(app.TabGroup);
            app.AdditionalsettingsTab.Title = 'Additional settings';
            app.Panel_2 = uipanel(app.AdditionalsettingsTab);
            app.Panel_2.Tag = 'Panel for more test settings';
            app.TestmethodButtonGroup = uibuttongroup(app.Panel_2);
            app.TestmethodButtonGroup.Title = 'Test method';
            app.FixedSNRButton = uiradiobutton(app.TestmethodButtonGroup);
            app.FixedSNRButton.Text = 'Fixed SNR';
            app.FixedSNRButton.Value = true;
            app.AdaptiveButton = uiradiobutton(app.TestmethodButtonGroup);
            app.AdaptiveButton.Text = 'Adaptive';
            app.OutputsetupButtonGroup = uibuttongroup(app.Panel_2);
            app.OutputsetupButtonGroup.Title = 'Output setup';
            app.HeadphonesButton = uiradiobutton(app.OutputsetupButtonGroup);
            app.HeadphonesButton.Text = 'Headphones';
            app.HeadphonesButton.Value = true;
            app.LoudspeakersButton = uiradiobutton(app.OutputsetupButtonGroup);
            app.LoudspeakersButton.Text = 'Loudspeakers';
            app.CalibrationButton = uibutton(app.OutputsetupButtonGroup, 'push');
            app.CalibrationButton.Text = 'Calibration';
            app.PresentationButtonGroup = uibuttongroup(app.Panel_2);
            app.PresentationButtonGroup.Title = 'Presentation';
            app.AudioonlyButton = uiradiobutton(app.PresentationButtonGroup);
            app.AudioonlyButton.Text = 'Audio only';
            app.AudioonlyButton.Value = true;
            app.AudiovisualButton = uiradiobutton(app.PresentationButtonGroup);
            app.AudiovisualButton.Text = 'Audiovisual';
            app.VideosetupButton = uibutton(app.PresentationButtonGroup, 'push');
            app.VideosetupButton.Text = 'Video setup';
            app.ScoringtypeButtonGroup = uibuttongroup(app.Panel_2);
            app.ScoringtypeButtonGroup.Title = 'Scoring type';
            app.KeywordbasedButton = uiradiobutton(app.ScoringtypeButtonGroup);
            app.KeywordbasedButton.Text = 'Keyword-based';
            app.KeywordbasedButton.Value = true;
            app.WordbywordButton = uiradiobutton(app.ScoringtypeButtonGroup);
            app.WordbywordButton.Text = 'Word-by-word';
            app.secLabel = uilabel(app.Panel_2);
            app.secLabel.Text = 'sec';
            app.MaskertimeEditFieldLabel = uilabel(app.Panel_2);
            app.MaskertimeEditFieldLabel.Text = 'Masker time';
            app.MaskertimeEditField = uieditfield(app.Panel_2, 'text');
            app.MaskertimeEditField.Value = '3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAST_V1_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end